classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ControlRobotWithControllerLabel  matlab.ui.control.Label
        ManualControlButton      matlab.ui.control.Button
        StartSimulationLabel     matlab.ui.control.Label
        ChooseADrinkToMakeLabel  matlab.ui.control.Label
        BarSimulationLabel       matlab.ui.control.Label
        EmergencyStopButton      matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        LoadEnvironmentButton    matlab.ui.control.Button
        Drink1Button             matlab.ui.control.Button
        Drink2Button             matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Creat timer that constantly checks ESTOP
        estopTimer = timer('ExecutionMode', 'fixedRate', ...
                           'Period', 0.1, ... % Check every 0.1 seconds
                           'TimerFcn', @(~,~) checkEmergencyStop(app))
        buttonPressed = false
        a
        state

% CODE FROM ASSIGNMENT2()

        robot1 = [];% Robot 1 model
        gripperBase1 = []; % Robot 1's gripper base model
        gripperLeft1 = cell(1,2); % stores (gripper claw 1, angle)
        gripperRight1 = cell(1,2); % stores (gripper claw 2, angle)

        robot2 = [];% Robot 2 model
        gripperBase2 = []; % Robot 2's gripper base model
        gripperLeft2 = cell(1,2); % stores (gripper claw 1, angle)
        gripperRight2 = cell(1,2); % stores (gripper claw 2, angle)

        bottles = cell(1,9); % Array to store bottle objects
        bottlePositions = []; % Array to store bottle positions
        holdbottle_ = [false, 1]; % stores whether the gripper is holding a bottle, and the index of the bottle (from array 'bottles')
        
        steps = 14; %should be an even number
        dt = 0.05;  % Discrete time step for simulation
        
        safeBound = [];
        elip1 = [];
        elip2 = [];
        cyl = [];

        % Shelf positions (x1 y1 z1; x2 y2 z2; etc)
        shelf_pos_arr = [0.8 2.15 1.48;
                         0.8 2.15 1.1;
                         0.6 2.15 1.48;
                         0.6 2.15 1.1];

        joy = [];
        joy_status = false;
        joyToggle = false;

        isEmergencyStopTriggered = false;

    end
    
    
    methods (Access = public)

        function checkEmergencyStop(app)
        % function to check if the estop is pressed

        % Read the state of the emergency stop button from Arduino
        app.state = readDigitalPin(app.a, 'D2');
            
            % If either GUI or Arduino ESTOP button is pressed
            if (app.EmergencyStopButton.UserData) || (app.state && ~app.buttonPressed)
                % Update logic statements
                app.self.isEmergencyStopTriggered = true; % not sure why doesn't work
                app.buttonPressed = true;
                disp('ESTOP PRESSED1');
                % Trigger the Emergency Stop callback
                EmergencyStopButtonPushed(app);
        
            end
        end

% CODE FROM ASSIGNMENT2()
        %% Initialize Scene
        function app = initialiseScene(app) % Load and place objects in the scene


            %Plot the robot in it's initial configuration
            clf;
            app.robot1 = UR30(eye(4) * transl(0,1.45,1) * trotx(0) * troty(0));
            hold on;
            app.robot2 = UR30(eye(4) * transl(0,-0.5,1) * trotx(0) * troty(0));

         
            xlim([-5, 5]);
            ylim([-5, 5]);
            zlim([-5, 5]);
            axis tight;
            app.robot1.model.animate(deg2rad([-230 -60 100 -137 -5 90])); % Robot's initial joint configuration
            app.gripperBase1 = PlaceObject('gripperBase.ply',[0,0,0]);
            app.gripperLeft1{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperLeft1{2} = deg2rad(20);
            app.gripperRight1{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperRight1{2} = deg2rad(20);

            app.robot2.model.animate(deg2rad([-10 -60 100 -137 -5 90])); % Robot's initial joint configuration
            app.gripperBase2 = PlaceObject('gripperBase.ply',[0,0,0]);
            app.gripperLeft2{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperLeft2{2} = deg2rad(20);
            app.gripperRight2{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperRight2{2} = deg2rad(20);

            app = app.commandGrippers('update');
            
            
            % Plot objects around the envionment

            % Add wood to floor
            surf([-4,-4;4,4] ...        % x axis
            ,[-4,3;-4,3] ...            % y axis
            ,[0,0;0,0] ...          % z axis
            ,'CData',imread('floor.jpg') ...
            ,'FaceColor','texturemap');
          
            
            %  % Add concrete to wall
            surf([-4,-4;4,4] ...        % x axis
            ,[3,3;3,3] ...               % y axis
            ,[0,3;0,3] ...                 % z axis
            ,'CData',imread('floor.jpg') ...
            ,'FaceColor','texturemap');

            % Add fire esintguisher sign to wall
            surf([2,3;2,3] ...               % x axis
            ,[2.99,2.99;2.99,2.99] ...                           % y axis
            ,[2.5,2.5;1.25,1.25] ...                 % z axis
            ,'CData',imread('FireExtinguisherSign.png') ...
            ,'FaceColor','texturemap');

            % Place the bar at specified location
            PlaceObject('Bar.PLY',[0,0,0]);

            % Place the fire extinguisher at specified location
            PlaceObject('fireExtinguisherElevated.ply',[2.5,2.25,0.465]);

            % Place the estop at specified location
            PlaceObject('emergencyStopButton.ply',[1.7,-1.7,1.1]);

            % Place the estop at specified location
            PlaceObject('emergencyStopButton.ply',[-1.7,-1.7,1.1]);

            t = hgtransform;
            robot1Pole = PlaceObject('robot1Pole.ply',[0,0,0]);
            set(robot1Pole, 'Parent', t);
            set(t, 'Matrix', transl(0,1.57,-0.02));

            t = hgtransform;
            midTable = PlaceObject('midTable.ply',[0,0,0]);
            set(midTable, 'Parent', t);
            set(t, 'Matrix', transl(0,0.5,0.2));

            % Place the ingredients at specified locations
            t = hgtransform;
            app.bottles{1} = PlaceObject('Whisky.ply',[0,0,0]);
            set(app.bottles{1}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(1,:))));
            
            t = hgtransform;
            app.bottles{2} = PlaceObject('Vodka.ply',[0,0,0]);
            set(app.bottles{2}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(2,:))));
            
            t = hgtransform;
            app.bottles{3} = PlaceObject('Cognac.ply',[0,0,0]);
            set(app.bottles{3}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(3,:))));

            t = hgtransform;
            app.bottles{4} = PlaceObject('Glass.ply',[0,0,0]);
            set(app.bottles{4}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(4,:))));

           
            drawnow();
            Logger().write('Initialisation finished');
        end
        
        %% Control Loop
        function app = controlLoop(app)
           run = true;
           while run == true
                % Looped control code in here

                app = app.straightDrink1();
                app = app.straightDrink2();
                app = app.straightDrink3();


               run = false;
           end

           
           Logger().write('Program Completed');
        end

        %% Gui command to mix drink variant 1
        function app = mixerOption1(app)
            

        end

        %% Gui command to mix drink variant 2
        function app = mixerOption2(app)
            

        end

        %% Gui command to pour straight drink variant 1
        function app = straightDrink1(app)
            
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.12, app.shelf_pos_arr(1,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app.holdbottle_ = [true,1];
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * rpy2tr(-pi/2,0,pi));
            disp(app.isEmergencyStopTriggered);
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, -0.5), [1 0 0 0 0 0]);
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, 0.5), [1 0 0 0 0 0]);
            app.holdbottle_ = [false,1];
            app.SnapBottleTo(1, app.shelf_pos_arr(1,:), [0,0,0]);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * rpy2tr(-pi/2,0,pi));
            
        end

        %% Gui command to pour straight drink variant 2
        function app = straightDrink2(app)
            
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.3, app.shelf_pos_arr(2,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.12, app.shelf_pos_arr(2,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app.holdbottle_ = [true,2];
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.3, app.shelf_pos_arr(2,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, -0.5), [1 0 0 0 0 0]);
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, 0.5), [1 0 0 0 0 0]);
            app.holdbottle_ = [false,2];
            app.SnapBottleTo(2, app.shelf_pos_arr(2,:), [0,0,0]);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.3, app.shelf_pos_arr(2,3)+0.1) * rpy2tr(-pi/2,0,pi));
        end

        %% Gui command to pour straight drink variant 3
        function app = straightDrink3(app)
            
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.08) * rpy2tr(-pi/2,0,pi));
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.12, app.shelf_pos_arr(3,3)+0.08) * rpy2tr(-pi/2,0,pi));
            app.holdbottle_ = [true,3];
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.1) * rpy2tr(-pi/2,0,pi));
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, -0.5), [1 0 0 0 0 0]);
            app = app.moveToPoseRMRC(app.getPoseT() * transl(0, 0, 0.5), [1 0 0 0 0 0]);
            app.holdbottle_ = [false,3];
            app.SnapBottleTo(3, app.shelf_pos_arr(3,:), [0,0,0]);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.08) * rpy2tr(-pi/2,0,pi));
        end


        %% get pose.T
        function output = getPoseT(app)
            output = app.robot1.model.fkine(app.robot1.model.getpos()).T;
            
        end

        %% get Joint State
        function output = getJointState(app, pose)
            output = app.robot1.model.ikcon(pose, app.robot1.model.getpos()); % joint values for target pose
        end


        %% Move Bottle
        function HoldBottle(app, bottleNum)
            % Update the position of a bottle based on the input handle and 
            % end effector pos, i.e. make the relative pose of the bottle 
            % 'sticky' to the pose of the end effector

            t = hgtransform;
            set(app.bottles{bottleNum}, 'Parent', t);
            set(t, 'Matrix', (app.getPoseT())* trotx(pi/2) * transl(0,0.12,-0.1));
        end
        
        %% Teleport Bottle
        function SnapBottleTo(app, bottleNum, trans, rpy)
            % Update the position of a bottle based on the input handle and transform + rotation values

            t = hgtransform;
            set(app.bottles{bottleNum}, 'Parent', t);
            set(t, 'Matrix', (transl(trans) * rpy2tr(rpy)));
        end

        %% Draw Grippers
        function app = commandGrippers(app, command)
            
                       
            switch(command)
                case 'update'
                    t1 = hgtransform;
                    set(app.gripperLeft1{1}, 'Parent', t1);
                    set(t1, 'Matrix', (app.getPoseT() * transl(-0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotx(pi) * trotz(app.gripperLeft1{2})));
                    
                    t2 = hgtransform;
                    set(app.gripperRight1{1}, 'Parent', t2);
                    set(t2, 'Matrix', (app.getPoseT() * transl(0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotz(app.gripperRight1{2})));
                    
                    t3 = hgtransform;
                    set(app.gripperBase1, 'Parent', t3);
                    set(t3, 'Matrix', (app.getPoseT() * transl(0, 0, 0) * trotx(-pi/2) * trotz(-pi/2)));
                    
    
                case 'open'
                    Logger().write('open claw');
                    Logger().write(' ');
                    app.steps = app.steps/2;


                    app.steps = app.steps*2;
    
                case 'close'
                    Logger().write('close claw');
                    Logger().write(' ');
                    app.steps = app.steps/2;


                    app.steps = app.steps*2;
            end
        
        end
        
        %% Update graphics
        function app = frameUpdate(app)
           
            app = app.commandGrippers('update');
            if app.holdbottle_(1) == true
                app.HoldBottle(app.holdbottle_(2));
            end
            

            if (app.joy_status == true)
                [axes, buttons, povs] = read(app.joy); %get controller values
                if (buttons(13) == 1) % if ps5 button is pressed, loop manual control code until ps5 button is released then pressed again
                    app.joyToggle = true; % manually control variable on
                    button_release = false;
                    
                    while (app.joyToggle == true) 
                        app = app.commandGrippers('update');
                        joystick_command();
                        
                        if (buttons(13) == 0)
                            button_release = true;
                        end
                        if (button_release == true && buttons(13) == 1)
                            app.joyToggle = false;
                        end
                    end
                    pause(2);
                end
            end

            drawnow();
            pause(app.dt);
            
        end

        %% Free move to given pose with ikcon
        function app = moveToPoseIkcon(app, pose)
            
            q0 = app.robot1.model.getpos(); % Initial joint values
            qf = app.robot1.model.ikcon(pose, q0); % joint values for target pose
           for q = 1:size(qf,2)
                while abs(qf(q)) > 2*pi
                    if qf(q) > 0
                        qf(q) = qf(q) - pi;
                    else
                        qf(q) = qf(q) + pi;
                    end
                end
            end

            qMatrix = jtraj(q0, qf, app.steps);

            Logger().write(['   Target Joint State: ',mat2str(qf)]);
            Logger().write(['   Target Pose: ',mat2str(pose)]);
            
            for frame = 1:app.steps

                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);
                 
                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);

                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                    app.robot1.model.animate(qMatrix(frame,:));
    
                    app = app.frameUpdate();
                end
            end
            ActPose = app.getPoseT();
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',num2str(sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)),' Failed to achieve desired pose']);

            else
            
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
                Logger().write(' ');

            end
        end


        %% Free move to given pose with Resolved Motion Rate Control (RMRC) 
        function app = moveToPoseRMRC(app, pose, mask)
            
            q0 = app.robot1.model.getpos();

            T0 = app.getPoseT();     % Starting position
            Tf = pose;                              % Ending position

            x0 = transl(T0);                        % Starting position (x, y, z)
            xf = transl(Tf);                        % Target position (x, y, z)

            rpy0 = tr2rpy(T0);                      % Starting orientation (roll, pitch, yaw)
            rpyf = tr2rpy(Tf);                      % Target orientation (roll, pitch, yaw)

            s = lspb(0, 1, app.steps);             % LSPB interpolation scalar

            lambda = 0.2;
            
            % Initialize arrays for storing trajectory
            pos_trajectory = zeros(3, app.steps);     % Position trajectory (x, y, z)
            rpy_trajectory = zeros(3, app.steps);     % Orientation trajectory (roll, pitch, yaw)
            
            % Interpolate both position and orientation
            for i = 1:app.steps
                
                 if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos(); 
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                
                % Linear interpolation for position (3D space)
                pos_trajectory(:, i) = x0*(1-s(i)) + s(i)*xf;
                
                % Linear interpolation for orientation (RPY)
                rpy_trajectory(:, i) = rpy0*(1-s(i)) + s(i)*rpyf;
                 end
            end
            
            qMatrix = nan(app.steps,6);
            qMatrix(1,:) = app.robot1.model.ikine(T0, 'q0', q0, 'mask', mask);                 % Solve for joint angles

            for i = 1:app.steps-1

                 if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                else

                xdot_pos = (pos_trajectory(:, i+1) - pos_trajectory(:, i)) / app.dt;  % Linear velocity
                xdot_ori = (rpy_trajectory(:, i+1) - rpy_trajectory(:, i)) / app.dt;  % Angular velocity
                xdot = [xdot_pos; xdot_ori];   % Full twist (6x1 vector: [vx; vy; vz; wx; wy; wz])

                J = app.robot1.model.jacob0(qMatrix(i,:));                   % Get the Jacobian at the current state
                Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
                qdot = Jinv_dls*xdot;                                              % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + app.dt*qdot';             % Update next joint state
                 end
            end

            for frame = 1:app.steps 
                
                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                
                app.robot1.model.animate(qMatrix(frame,:));

                app = app.frameUpdate();
                end

            end
            ActPose = app.getPoseT();
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',num2str(sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)),' Failed to achieve desired pose']);
            
            else
            
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
                Logger().write(' ');

            end
        end

        
        %% Move to given Joint State
        function app = moveManual(app, qf)

            q0 = app.robot1.model.getpos(); % Initial joint values          

            qSuperMatrix = zeros(app.steps, size(qf,2));
            for i = 1:size(qf,2)
                if ~isnan(qf(i))
                    qMatrix = jtraj(q0(i), qf(i), app.steps);
                    qSuperMatrix(:,i) = qMatrix;
                else
                    qSuperMatrix(:,i) = q0(i);
                    qf(i) = q0(i);
                end
            end

            pose = app.robot1.model.fkine(qf).T;
            Logger().write(['   Moving to Joint State ',mat2str(qf)]);
            Logger().write(['   Target Pose ',mat2str(pose)]);
            
            for frame = 1:app.steps 
                
                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                else

                app.robot1.model.animate(qSuperMatrix(frame,:));

                app = app.frameUpdate();
                end
            end
            ActPose = app.getPoseT();
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                Logger().write(['   Δ = ',sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2),' Failed to achieve desired pose']);

            else

            Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
            Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
            Logger().write(' ');

            end
        end

        %% simultaneous movement demo
        % function simulMoveDemo(self)
            % self.robot1.model.animate(deg2rad([-0.1 0 90 -90 -90 -90 90 -90]));
            % 
            % endAffectorTr = self.getPoseT() * troty(-pi/2);
            % self.gripperLeft1.model.base = endAffectorTr;
            % self.gripperRight1.model.base = endAffectorTr * troty(pi);
            % self.gripperLeft1.model.animate(deg2rad(190));
            % self.gripperRight1.model.animate(deg2rad(10));
            % 
            % q0 = self.robot1.model.getpos(); % Initial joint values
            % qf = self.robot1.model.ikcon(self.getPoseT * transl(0.2,0.2,0), q0); % joint values for target pose
            % 
            % for q = 1:size(qf,2)
            %     if abs(qf(q)) > 2*pi
            %         if qf(q) > 0
            %             qf(q) = qf(q) - pi;
            %         else
            %             qf(q) = qf(q) + pi;
            %         end
            %     end
            % end
            % 
            % qMatrixBase = jtraj(q0, qf, 100);
            % qMatrixRight  = jtraj(self.gripperLeft1.model.getpos(), deg2rad(170), 100);
            % qMatrixLeft  = jtraj(self.gripperRight1.model.getpos(), deg2rad(-10), 100);
            % 
            % for frame = 1:100 
            %     self.robot1.model.animate(qMatrixBase(frame,:));
            % 
            %     endAffectorTr = self.getPoseT() * troty(-pi/2);
            %     self.gripperLeft1.model.base = endAffectorTr;
            %     self.gripperRight1.model.base = endAffectorTr * troty(pi);
            %     self.gripperLeft1.model.animate(qMatrixLeft(frame,:));
            %     self.gripperRight1.model.animate(qMatrixRight(frame,:));
            % 
            %     self = self.frameUpdate();
            % end
            % 
            % pause(3);
            % self = self.commandGrippers('open');
            % self.robot1.model.animate(deg2rad([-0.1 0 90 -90 -90 -90 90 -90]));
            % self = self.commandGrippers('update');
        % end
        %% Workspace Radius and Volume
        function app = plotRadAndVol(app)
            disp('The reach of the stationary UR3e can roughly be calculated as 0.9410m')
            disp('vertically from the base, and 0.7410m horizontally. The reach of the')
            disp('UR30 can be calculated to be XXXm vertically and XXXm horizontally.')
            disp(' ')
            disp('The robots cannot reach below their relative height Z=0, therefore the volumes of their')
            disp('reach is determined as half-ellipsoids centred on the robots base ');
            disp(' ')
            disp('All together, this represents a working volume of:')
            disp('(2π/3)*(0.9410 * 0.7410^2)')
            disp(['    = ',num2str((2*pi/3)*(0.9410 * 0.7410^2)),' cubic metres for the UR3e, and:']);
            disp('(2π/3)*(XXXX * XXXXX^2)')
            disp(['    = ',num2str((2*pi/3)*(0.9410 * 0.7410^2)),' cubic metres for the UR30.']);


            % NOTE: The basis of the following surf drawing code was adapted from code generated by AI 
            % Parameters for the ellipsoid 1
            [a, b, c] = deal(0.7410, 0.7410, 0.9410);  % Radii along the x, y, and z axes

            % Create the ellipsoid data for UR3e
            [xe, ye, ze] = ellipsoid(0, 0, 0.5, a, b, c, 100);
            % Mask to keep only the lower half of the ellipsoid
            zmask = ze >= 0.5;  % True for points at or above z = 0.5
            xmask = xe >= 0;
            mask = zmask & xmask;
            xe(~mask) = NaN;
            ye(~mask) = NaN;
            ze(~mask) = NaN;
            
            % Define a grid for the half-ellipsoid surface
            patchData = surf2patch(xe, ye, ze);
            app.elip1 = patch(patchData);
            set(app.elip1, 'FaceColor', [0, 1, 0]);  % RGB color (e.g., red)
            set(app.elip1, 'FaceAlpha', 0.2);        % Transparency (0 is fully transparent, 1 is fully opaque)
            set(app.elip1, 'EdgeColor', 'none');     % Remove edges for smoother appearance

            % Parameters for the ellipsoid 2
            [a, b, c] = deal(0.7410, 0.7410, 0.9410);  % Radii along the x, y, and z axes

            % Create the ellipsoid data
            [xe, ye, ze] = ellipsoid(-0.8, 0, 0.5, a, b, c, 100);
            % Mask to keep only the lower half of the ellipsoid
            zmask = ze >= 0.5;  % True for points at or above z = 0.5
            xmask = xe <= -0.8;
            mask = zmask & xmask;
            xe(~mask) = NaN;
            ye(~mask) = NaN;
            ze(~mask) = NaN;
            
            % Define a grid for the half-ellipsoid surface
            patchData = surf2patch(xe, ye, ze);
            app.elip2 = patch(patchData);
            set(app.elip2, 'FaceColor', [0, 1, 0]);  % RGB color (e.g., red)
            set(app.elip2, 'FaceAlpha', 0.2);        % Transparency (0 is fully transparent, 1 is fully opaque)
            set(app.elip2, 'EdgeColor', 'none');     % Remove edges for smoother appearance
            
            % Parameters for the middle cylinder
            radius_major = 0.9410; % Major radius (along y-axis)
            radius_minor = 0.7410; % Minor radius (along z-axis)

            [zc, yc, xc] = cylinder(1, 150);  % Minor radius for circular base
            zc = (zc * radius_major) + 0.5;
            yc = yc * radius_minor;
            xc = xc * -0.82;
            mask = zc >= 0.5;  % True for points at or above z = 0.5
            xc(~mask) = NaN;
            yc(~mask) = NaN;
            zc(~mask) = NaN;

            patchData = surf2patch(xc, yc, zc);
            app.cyl = patch(patchData);

            set(app.cyl, 'FaceColor', [0, 1, 0]);  % RGB color (e.g., red)
            set(app.cyl, 'FaceAlpha', 0.2);        % Transparency (0 is fully transparent, 1 is fully opaque)
            set(app.cyl, 'EdgeColor', 'none');     % Remove edges for smoother appearance
            
            app.moveManual([0 0 0 0 0 -pi/2 0 0]);
            app.moveManual([0 0 -pi/2 0 0 -pi/2 0 0]);
            app.moveManual([0 0 pi/2 0 0 -pi/2 0 0]);
            app.moveManual([0 0 pi/2 -pi/2 0 -pi/2 0 0]);
            app.moveManual([0 0 -pi/2 0 0 -pi/2 0 0]);
            app.robot1.model.animate(deg2rad([-0.1 0 90 -90 -90 -90 90 -90]));
            app.commandGrippers('update');
            
            app.safeBound = [app.elip1 app.elip2 app.cyl];
            
        end
        
        %% Hide safe Zone
        function app = hideVol(app)
            delete(app.safeBound);
        end

        %% Run
        function app = runAll(app)
            app = app.initialiseScene();
            app = app.plotRadAndVol();
            app = app.hideVol();
            app = app.controlLoop();
        end

%% Setter function
        function app = EmergencyStopTriggeredTrue(app)

            app.isEmergencyStopTriggered = true;
            disp('Emergency stop triggered ');

        end

%% Setter function
        function app = EmergencyStopTriggeredFalse(app)

            app.isEmergencyStopTriggered = false;
            disp('Emergency stop reset ');

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Drink1Button
        function Drink1ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 1
            app = app.straightDrink1();

            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.Drink1Button.Enable = 'on';
                app.Drink2Button.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: Drink2Button
        function Drink2ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 2
            app = app.straightDrink2();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.Drink1Button.Enable = 'on';
                app.Drink2Button.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            % Turn off load environment button
            % only pressed once at the start
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.LoadEnvironmentButton.Enable = 'off';
            
            % Start the timer that checks if arduino estop is pressed
            if strcmp(app.estopTimer.Running, 'on')
                stop(app.estopTimer);
            end
            start(app.estopTimer);
            
            % Load environment
            app = app.initialiseScene();
            
            % Set up arduino 
            % clear app.a;
            % app.a = arduino();
            
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.ManualControlButton.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.ResumeButton.Enable = 'off';
            app = app.EmergencyStopTriggeredFalse();
             
            app.EmergencyStopButton.UserData = false;
            app.buttonPressed = false;
            app.ResumeButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EmergencyStopButton.UserData = true;
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app = app.EmergencyStopTriggeredTrue();
            
            %       app.self.robot1.model.animate(CurrentPos);
            disp('ESTOP PRESSED2');
            disp(app.isEmergencyStopTriggered);

            app.ResumeButton.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
        end

        % Button pushed function: ManualControlButton
        function ManualControlButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
          
            % Call function for controller
            %app.self = app.self.moveManual();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.Drink1Button.Enable = 'on';
                app.Drink2Button.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Drink2Button = uibutton(app.UIFigure, 'push');
            app.Drink2Button.ButtonPushedFcn = createCallbackFcn(app, @Drink2ButtonPushed, true);
            app.Drink2Button.Enable = 'off';
            app.Drink2Button.Text = 'Drink2';
            app.Drink1Button = uibutton(app.UIFigure, 'push');
            app.Drink1Button.ButtonPushedFcn = createCallbackFcn(app, @Drink1ButtonPushed, true);
            app.Drink1Button.Enable = 'off';
            app.Drink1Button.Text = 'Drink1';
            app.LoadEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Text = 'Resume';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BarSimulationLabel = uilabel(app.UIFigure);
            app.BarSimulationLabel.Text = 'Bar Simulation';
            app.ChooseADrinkToMakeLabel = uilabel(app.UIFigure);
            app.ChooseADrinkToMakeLabel.Text = 'Choose A Drink To Make';
            app.StartSimulationLabel = uilabel(app.UIFigure);
            app.StartSimulationLabel.Text = 'Start Simulation';
            app.ManualControlButton = uibutton(app.UIFigure, 'push');
            app.ManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @ManualControlButtonPushed, true);
            app.ManualControlButton.Enable = 'off';
            app.ManualControlButton.Text = 'Manual Control';
            app.ControlRobotWithControllerLabel = uilabel(app.UIFigure);
            app.ControlRobotWithControllerLabel.Text = 'Control Robot With Controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end